classdef mainAPP_inner_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadEEGFileMenu                 matlab.ui.container.Menu
        LoadMusicFileMenu               matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        CreateATensorMenu               matlab.ui.container.Menu
        ExtractMusicalFeaturesMenu      matlab.ui.container.Menu
        EvaluatenumberofcomponentsMenu  matlab.ui.container.Menu
        TensorDecompositionMenu         matlab.ui.container.Menu
        CorrelationAnalysisMenu         matlab.ui.container.Menu
        EEGPanel                        matlab.ui.container.Panel
        Label_EEGFilename               matlab.ui.control.Label
        Label_EEGChannels               matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        Label_EEGTimes                  matlab.ui.control.Label
        TimessLabel                     matlab.ui.control.Label
        Label_EEGSrate                  matlab.ui.control.Label
        SamplingrateHzLabel             matlab.ui.control.Label
        Label_ChannelLocations_Yes_No   matlab.ui.control.Label
        ChannellocationsLabel           matlab.ui.control.Label
        OngoingEEGLabLabel              matlab.ui.control.Label
        MusicPanel                      matlab.ui.container.Panel
        Label_MusicFilename             matlab.ui.control.Label
        Label_MusicSoundtracks          matlab.ui.control.Label
        SoundtracksLabel                matlab.ui.control.Label
        FilenameLabel_2                 matlab.ui.control.Label
        Label_MusicTimes                matlab.ui.control.Label
        TimessLabel_2                   matlab.ui.control.Label
        Label_MusicSrate                matlab.ui.control.Label
        SamplingrateHzLabel_2           matlab.ui.control.Label
        TensorDecompositionCorrelationPanel  matlab.ui.container.Panel
        Label_NumComponents             matlab.ui.control.Label
        NumofComponentsLabel            matlab.ui.control.Label
        Label_Runs                      matlab.ui.control.Label
        RunsLabel                       matlab.ui.control.Label
        Label_Method                    matlab.ui.control.Label
        MethodLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        pathname             % base path of current file
        EEG                  % a strct object of EEG
        MUSIC                % a struct object of music
        TP                   % a struct object of tensor decomposition
    end
    
    methods (Access = private)
%         function results = loadMUSIC(app)
%             [file, path,~] = uigetfile({'*.wav;*.mp3';},'Import Audio Data');
%             [app.MUSIC.data, app.MUSIC.srate] = audioread([path filesep file]);
%             app.MUSIC.filename = file;
%             app.MUSIC.filepath = path;
%             results = app.MUSIC;
%         end
        
%         function pose_ahead(app)
%             movegui(app.UIFigure, 'onscreen');
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initializing 
            fprintf('Initializing ... \n');
            app.pathname = fileparts(mfilename('fullpath'));
            
            addpath(genpath(app.pathname));
            
            app.UIFigure.Name = '';
        end

        % Menu selected function: LoadEEGFileMenu
        function LoadEEGFileMenuSelected(app, event)
            app.EEG = loadEEG();
            
            filename = app.EEG.filename;
            Nchans = app.EEG.nbchan;
            Times = app.EEG.pnts/app.EEG.srate;
            Srate = app.EEG.srate;
            if isempty(app.EEG.chanlocs)
                channelLocation = 'No';
            else
                channelLocation = 'Yes';
            end
            
            app.Label_EEGFilename.Text = filename;
            app.Label_EEGChannels.Text = num2str(Nchans);
            app.Label_EEGTimes.Text = num2str(Times);
            app.Label_EEGSrate.Text = num2str(Srate);
            app.Label_ChannelLocations_Yes_No.Text = channelLocation;
        end

        % Menu selected function: LoadMusicFileMenu
        function LoadMusicFileMenuSelected(app, event)
            app.MUSIC = loadMUSIC();
            
            filename = app.MUSIC.filename;
            Nchans = size(app.MUSIC.data, 2);
            Times = size(app.MUSIC.data, 1)/app.MUSIC.srate;
            Srate = app.MUSIC.srate;

            app.Label_MusicFilename.Text = filename;
            app.Label_MusicSoundtracks.Text = num2str(Nchans);
            app.Label_MusicTimes.Text = num2str(Times);
            app.Label_MusicSrate.Text = num2str(Srate);
        end

        % Menu selected function: CreateATensorMenu
        function CreateATensorMenuSelected(app, event)
            getTensor(app.EEG, app.pathname);
        end

        % Menu selected function: ExtractMusicalFeaturesMenu
        function ExtractMusicalFeaturesMenuSelected(app, event)
            getLongTermFeature(app.MUSIC, app.pathname);
        end

        % Menu selected function: EvaluatenumberofcomponentsMenu
        function EvaluatenumberofcomponentsMenuSelected(app, event)
            autoTensorDecomp(app.pathname);
        end

        % Menu selected function: TensorDecompositionMenu
        function TensorDecompositionMenuSelected(app, event)
            [app.TP.R, app.TP.Runs, app.TP.Method_Flag] = tensorDecomp(app.pathname);
            app.TP.Method = {'iAPG', 'NTF', 'NCP'};
            
            % fresh the informaion
            app.Label_NumComponents.Text = num2str(app.TP.R);
            app.Label_Runs.Text = num2str(app.TP.Runs);
            app.Label_Method.Text = app.TP.Method{app.TP.Method_Flag};
        end

        % Menu selected function: CorrelationAnalysisMenu
        function CorrelationAnalysisMenuSelected(app, event)
             corrAnalysis(app.pathname, app.EEG.chanlocs, app.TP.R);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadEEGFileMenu = uimenu(app.FileMenu);
            app.LoadEEGFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadEEGFileMenuSelected, true);
            app.LoadEEGFileMenu.Text = 'Load EEG File';
            app.LoadMusicFileMenu = uimenu(app.FileMenu);
            app.LoadMusicFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMusicFileMenuSelected, true);
            app.LoadMusicFileMenu.Text = 'Load Music File';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CreateATensorMenu = uimenu(app.ToolsMenu);
            app.CreateATensorMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateATensorMenuSelected, true);
            app.CreateATensorMenu.Text = 'Create A Tensor';
            app.ExtractMusicalFeaturesMenu = uimenu(app.ToolsMenu);
            app.ExtractMusicalFeaturesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractMusicalFeaturesMenuSelected, true);
            app.ExtractMusicalFeaturesMenu.Text = 'Extract Musical Features';
            app.EvaluatenumberofcomponentsMenu = uimenu(app.ToolsMenu);
            app.EvaluatenumberofcomponentsMenu.MenuSelectedFcn = createCallbackFcn(app, @EvaluatenumberofcomponentsMenuSelected, true);
            app.EvaluatenumberofcomponentsMenu.Text = 'Evaluate number of components';
            app.TensorDecompositionMenu = uimenu(app.ToolsMenu);
            app.TensorDecompositionMenu.MenuSelectedFcn = createCallbackFcn(app, @TensorDecompositionMenuSelected, true);
            app.TensorDecompositionMenu.Text = 'Tensor Decomposition';
            app.CorrelationAnalysisMenu = uimenu(app.ToolsMenu);
            app.CorrelationAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @CorrelationAnalysisMenuSelected, true);
            app.CorrelationAnalysisMenu.Text = 'Correlation Analysis';
            app.EEGPanel = uipanel(app.UIFigure);
            app.EEGPanel.Title = 'EEG';
            app.Label_EEGFilename = uilabel(app.EEGPanel);
            app.Label_EEGFilename.Text = '';
            app.Label_EEGChannels = uilabel(app.EEGPanel);
            app.Label_EEGChannels.Text = '';
            app.ChannelsLabel = uilabel(app.EEGPanel);
            app.ChannelsLabel.Text = 'Channels';
            app.FilenameLabel = uilabel(app.EEGPanel);
            app.FilenameLabel.Text = 'Filename';
            app.Label_EEGTimes = uilabel(app.EEGPanel);
            app.Label_EEGTimes.Text = '';
            app.TimessLabel = uilabel(app.EEGPanel);
            app.TimessLabel.Text = 'Times (s)';
            app.Label_EEGSrate = uilabel(app.EEGPanel);
            app.Label_EEGSrate.Text = '';
            app.SamplingrateHzLabel = uilabel(app.EEGPanel);
            app.SamplingrateHzLabel.Text = 'Sampling rate (Hz)';
            app.Label_ChannelLocations_Yes_No = uilabel(app.EEGPanel);
            app.Label_ChannelLocations_Yes_No.Text = '';
            app.ChannellocationsLabel = uilabel(app.EEGPanel);
            app.ChannellocationsLabel.Text = 'Channel locations';
            app.OngoingEEGLabLabel = uilabel(app.UIFigure);
            app.OngoingEEGLabLabel.Text = 'OngoingEEGLab';
            app.MusicPanel = uipanel(app.UIFigure);
            app.MusicPanel.Title = 'Music';
            app.Label_MusicFilename = uilabel(app.MusicPanel);
            app.Label_MusicFilename.Text = '';
            app.Label_MusicSoundtracks = uilabel(app.MusicPanel);
            app.Label_MusicSoundtracks.Text = '';
            app.SoundtracksLabel = uilabel(app.MusicPanel);
            app.SoundtracksLabel.Text = 'Soundtracks';
            app.FilenameLabel_2 = uilabel(app.MusicPanel);
            app.FilenameLabel_2.Text = 'Filename';
            app.Label_MusicTimes = uilabel(app.MusicPanel);
            app.Label_MusicTimes.Text = '';
            app.TimessLabel_2 = uilabel(app.MusicPanel);
            app.TimessLabel_2.Text = 'Times (s)';
            app.Label_MusicSrate = uilabel(app.MusicPanel);
            app.Label_MusicSrate.Text = '';
            app.SamplingrateHzLabel_2 = uilabel(app.MusicPanel);
            app.SamplingrateHzLabel_2.Text = 'Sampling rate (Hz)';
            app.TensorDecompositionCorrelationPanel = uipanel(app.UIFigure);
            app.TensorDecompositionCorrelationPanel.Title = 'Tensor Decomposition & Correlation';
            app.Label_NumComponents = uilabel(app.TensorDecompositionCorrelationPanel);
            app.Label_NumComponents.Text = '';
            app.NumofComponentsLabel = uilabel(app.TensorDecompositionCorrelationPanel);
            app.NumofComponentsLabel.Text = 'Num. of Components';
            app.Label_Runs = uilabel(app.TensorDecompositionCorrelationPanel);
            app.Label_Runs.Text = '';
            app.RunsLabel = uilabel(app.TensorDecompositionCorrelationPanel);
            app.RunsLabel.Text = 'Runs';
            app.Label_Method = uilabel(app.TensorDecompositionCorrelationPanel);
            app.Label_Method.Text = '';
            app.MethodLabel = uilabel(app.TensorDecompositionCorrelationPanel);
            app.MethodLabel.Text = 'Method';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainAPP_inner_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end